classdef Matrice_In < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EncodageduneMatriceUIFigure  matlab.ui.Figure
        Image                        matlab.ui.control.Image
        Load_Button                  matlab.ui.control.Button
        Max_zeros_Label              matlab.ui.control.Label
        Max_zeros_Label_2            matlab.ui.control.Label
        Max_zeros_Label_3            matlab.ui.control.Label
        Max_zeros_Label_4            matlab.ui.control.Label
        H_resultat                   matlab.ui.control.Label
        V_resultat                   matlab.ui.control.Label
        Z_resultat                   matlab.ui.control.Label
        retourButton                 matlab.ui.control.Button
        image_ori                    matlab.ui.control.UIAxes
        image_tra                    matlab.ui.control.UIAxes
        Resultat_finale              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load_Button
        function Load_ButtonPushed(app, event)
            global img;
            [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
            img = strcat(path,file);
            img=imread(img);
            imshow(img,'parent',app.image_ori);
            level = graythresh(img);
            Im = im2bw(img,level);
            imshow(Im,'parent',app.image_tra);
            %verify if it's square
            if ~diff(size(Im)) == 1
                H=horizontal(Im);
                V=vertical(Im);
                 Z=zigzag(Im);
            app.H_resultat.Text=sprintf('%.0f',H);
            app.V_resultat.Text=sprintf('%.0f',V);
            app.Z_resultat.Text=sprintf('%.0f',Z);
            if H>V
                if H > Z
                    app.Resultat_finale.Text="Horizontal contient la plus grand suite de 0";
                else
                    app.Resultat_finale.Text="Zigzag contient la plus grand suite de 0";
                end
            elseif H < V
                if V > Z
                    app.Resultat_finale.Text="Vertical contient la plus grand suite de 0";
                else
                    app.Resultat_finale.Text="Zigzag contient la plus grand suite de 0";
                end      
            end
            else
                app.Resultat_finale.Text="Entrez une matrice carré";
                pause on 
            end
            
        end

        % Callback function
        function EncoderButtonPushed(app, event)
         
        end

        % Button pushed function: retourButton
        function retourButtonPushed(app, event)
            Choose_Interface;
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EncodageduneMatriceUIFigure = uifigure('Visible', 'off');
            app.EncodageduneMatriceUIFigure.Name = 'Encodage d''une Matrice';
            app.Image = uiimage(app.EncodageduneMatriceUIFigure);
            app.Image.ImageSource = 'matrix_In.png';
            app.Load_Button = uibutton(app.EncodageduneMatriceUIFigure, 'push');
            app.Load_Button.ButtonPushedFcn = createCallbackFcn(app, @Load_ButtonPushed, true);
            app.Load_Button.Text = 'Load d''une image depuis votre machine';
            app.Max_zeros_Label = uilabel(app.EncodageduneMatriceUIFigure);
            app.Max_zeros_Label.Text = 'Le nombre maximal des Zeros';
            app.Max_zeros_Label_2 = uilabel(app.EncodageduneMatriceUIFigure);
            app.Max_zeros_Label_2.Text = 'Lecture Horizontale';
            app.Max_zeros_Label_3 = uilabel(app.EncodageduneMatriceUIFigure);
            app.Max_zeros_Label_3.Text = 'Lecture Verticale';
            app.Max_zeros_Label_4 = uilabel(app.EncodageduneMatriceUIFigure);
            app.Max_zeros_Label_4.Text = 'Lecture en Zigzag';
            app.H_resultat = uilabel(app.EncodageduneMatriceUIFigure);
            app.H_resultat.Text = '?';
            app.V_resultat = uilabel(app.EncodageduneMatriceUIFigure);
            app.V_resultat.Text = '?';
            app.Z_resultat = uilabel(app.EncodageduneMatriceUIFigure);
            app.Z_resultat.Text = '?';
            app.retourButton = uibutton(app.EncodageduneMatriceUIFigure, 'push');
            app.retourButton.ButtonPushedFcn = createCallbackFcn(app, @retourButtonPushed, true);
            app.retourButton.Text = 'retour';
            app.image_ori = uiaxes(app.EncodageduneMatriceUIFigure);
            title(app.image_ori, 'Image originale')
            xlabel(app.image_ori, {''; ''})
            ylabel(app.image_ori, '')
            app.image_ori.XTick = [];
            app.image_ori.YTick = [];
            app.image_tra = uiaxes(app.EncodageduneMatriceUIFigure);
            title(app.image_tra, 'Image en 0 et 1')
            xlabel(app.image_tra, '')
            ylabel(app.image_tra, '')
            app.image_tra.XTick = [];
            app.image_tra.YTick = [];
            app.Resultat_finale = uilabel(app.EncodageduneMatriceUIFigure);
            app.Resultat_finale.Text = '';
            % Show the figure after all components are created
            app.EncodageduneMatriceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Matrice_In

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EncodageduneMatriceUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EncodageduneMatriceUIFigure)
        end
    end
end