classdef Text_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        encoderuntextUIFigure       matlab.ui.Figure
        Background                  matlab.ui.control.Image
        EntrerletextTextArea        matlab.ui.control.TextArea
        Huffman_label               matlab.ui.control.Label
        ShanoFano_label             matlab.ui.control.Label
        EcoderButton                matlab.ui.control.Button
        results_label               matlab.ui.control.Label
        EntrerletextetencoderLabel  matlab.ui.control.Label
        operator                    matlab.ui.control.Label
        RetourButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
            
        end

        % Button pushed function: EcoderButton
        function EcoderButtonPushed(app, event)
            value = app.EntrerletextTextArea.Value;
            Text = char(value);
            % HUFFMAN
            symboles = unique(Text);
            for i=1:length(symboles)
                k = strfind(Text,symboles(i));
                n=length(k);
                pn(i)=n/length(Text);
            end
            symboles=double(symboles);
            m_code=huffmandict(symboles,pn);
            code2=huffmanenco(Text,m_code);
            % Taux de compression HUFFMAN
            taux_c=length(code2)/(length(Text)*8);
            app.Huffman_label.Text=num2str(taux_c)+"%";
            % shanon fano
            code=ShannonFanoCode(symboles,pn);
            m_code=EntropyEncoder(Text,code);
            m_code1=[];
            for i=1:length(m_code)
                m_code1=[m_code1 double(m_code{i})];
            end
            % taux de compression shanon fano
            taux_cS=length(m_code1)/(length(Text)*8);
            app.ShanoFano_label.Text=num2str(taux_cS)+"%";
            if taux_c == taux_cS
                app.results_label.Text="SONT EGAUX";
                app.operator.Text="=";
            elseif taux_c > taux_cS
                app.results_label.Text="Huffman est plus puissant";
                app.operator.Text=">";
            else
                app.results_label.Text="Shanon-fano est plus puissant";
                app.operator.Text="<";
            end
            %app.results_label.Text=char(huffmandeco(code2,m_code));
        end

        % Button pushed function: RetourButton
        function RetourButtonPushed(app, event)
            Choose_Interface;
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.encoderuntextUIFigure = uifigure('Visible', 'off');
            app.encoderuntextUIFigure.Name = 'encoder un text';
            app.Background = uiimage(app.encoderuntextUIFigure);
            app.Background.ImageSource = 'Text_in.png';
            app.EntrerletextTextArea = uitextarea(app.encoderuntextUIFigure);
            app.Huffman_label = uilabel(app.encoderuntextUIFigure);
            app.Huffman_label.Text = '??';
            app.ShanoFano_label = uilabel(app.encoderuntextUIFigure);
            app.ShanoFano_label.Text = '??';
            app.EcoderButton = uibutton(app.encoderuntextUIFigure, 'push');
            app.EcoderButton.ButtonPushedFcn = createCallbackFcn(app, @EcoderButtonPushed, true);
            app.EcoderButton.Text = 'Ecoder';
            app.results_label = uilabel(app.encoderuntextUIFigure);
            app.results_label.Text = '';
            app.EntrerletextetencoderLabel = uilabel(app.encoderuntextUIFigure);
            app.EntrerletextetencoderLabel.Text = 'Entrer le text et encoder';
            app.operator = uilabel(app.encoderuntextUIFigure);
            app.operator.Text = '';
            app.RetourButton = uibutton(app.encoderuntextUIFigure, 'push');
            app.RetourButton.ButtonPushedFcn = createCallbackFcn(app, @RetourButtonPushed, true);
            app.RetourButton.Text = 'Retour';
            % Show the figure after all components are created
            app.encoderuntextUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Text_Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.encoderuntextUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.encoderuntextUIFigure)
        end
    end
end